classdef wavPlayer_compressor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        sampelingfreqTextArea       matlab.ui.control.TextArea
        sampelingfreqTextAreaLabel  matlab.ui.control.Label
        playcompressedButton        matlab.ui.control.Button
        playoriginalButton          matlab.ui.control.Button
        LoadwavfileButton           matlab.ui.control.Button
        CenterPanel                 matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        RightPanel                  matlab.ui.container.Panel
        Lamp_2                      matlab.ui.control.Lamp
        MakeupSwitch                matlab.ui.control.Switch
        MakeupSwitchLabel           matlab.ui.control.Label
        CompressorLabel             matlab.ui.control.Label
        Lamp                        matlab.ui.control.Lamp
        compressorSwitch            matlab.ui.control.ToggleSwitch
        compressorSwitchLabel       matlab.ui.control.Label
        thresholddbSlider           matlab.ui.control.Slider
        thresholddbLabel            matlab.ui.control.Label
        ratioKnob                   matlab.ui.control.DiscreteKnob
        ratioKnobLabel              matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

        
        properties (Access = private)
             y_original
            fs
            CompressedSignal
    
        end
        
        methods (Access = private)
            
        function LoadwavfileButtonPushed(app, ~)
    [file, path] = uigetfile('*.wav');
    if isequal(file, 0)
        return;
    end
    fullFileName = fullfile(path, file);
    [y, fs] = audioread(fullFileName);   

    if size(y, 2) > 1
        y = mean(y, 2); 
    end

    app.y_original = y;
    app.fs = fs;                         

    app.sampelingfreqTextArea.Value = num2str(app.fs);

    t = (0:length(y) - 1) / app.fs;
    cla(app.UIAxes);
    app.UIAxes.XLimMode = 'auto';  
    app.UIAxes.YLimMode = 'auto'; 
    plot(app.UIAxes, t, y);
    xlabel(app.UIAxes, 'Time [s]  ');
    ylabel(app.UIAxes, 'Amplitude');
    title(app.UIAxes, 'Loaded Waveform');
    xlim(app.UIAxes, [0 t(end)]);

    ymin = min(y);
    ymax = max(y);
    if ymin == ymax
        ylim(app.UIAxes, [ymin - 0.01, ymax + 0.01]);
    else
        ylim(app.UIAxes, [ymin, ymax]);
    end
end

        function updateCompressorLamp(app)
    if strcmp(app.compressorSwitch.Value, 'On')
        app.Lamp.Color = [0 1 0];
    else
        app.Lamp.Color = [1 0 0]; 
    end
end

        function updateMakeupLamp(app)
    if strcmp(app.MakeupSwitch.Value, 'On')
        app.Lamp_2.Color = [0 1 0]; 
    else
        app.Lamp_2.Color = [1 0 0]; 
    end
end

        function y_out = compressSignal(~, x, ratio, threshold_dB)
    threshold = 10^(threshold_dB / 20);  % dB → linear
    knee_width = 0.05; %  טווח ברך רך: 
    y_out = zeros(size(x));

    for i = 1:length(x)
        xi = x(i);
        amp = abs(xi);

        if amp <= threshold - knee_width / 2
            y_out(i) = xi;  % אין דחיסה כלל
        elseif amp > threshold + knee_width / 2
            excess = amp - threshold;
            compressed_amp = threshold + excess / ratio;
            y_out(i) = sign(xi) * compressed_amp;
        else
            % אזור הברך – מעבר חלק בין לא דחוס לדחוס
            t = (amp - (threshold - knee_width/2)) / knee_width;  % מ-0 ל-1
            linear = xi;
            excess = amp - threshold;
            compressed_amp = threshold + excess / ratio;
            compressed = sign(xi) * compressed_amp;
            y_out(i) = (1 - t) * linear + t * compressed;
        end
    end
end

        function CompressorSwitchValueChanged(app, ~)
            value = app.CompressorSwitch.Value;
            
            if strcmp(value, 'On')
                app.Lamp.Color = [0 1 0];  
            else
                app.Lamp.Color = [1 0 0];  
            end
        end
        end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             updateCompressorLamp(app);
             updateMakeupLamp(app);
        end

        % Button pushed function: LoadwavfileButton
        function LoadwavfileButtonPushed2(app, event)
        app.LoadwavfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadwavfileButtonPushed, true);


        end

        % Button pushed function: playoriginalButton
        function playoriginalButtonPushed(app, event)
            if isempty(app.y_original)
        uialert(app.UIFigure, 'No audio loaded.', 'Error');
        return;
    end
    sound(app.y_original, app.fs);
    t = (0:length(app.y_original)-1) / app.fs;
    cla(app.UIAxes);
    app.UIAxes.XLimMode = 'auto';
    app.UIAxes.YLimMode = 'auto';
    plot(app.UIAxes, t, app.y_original);
    title(app.UIAxes, 'Original Signal');
    xlabel(app.UIAxes, 'Time[s]');
    ylabel(app.UIAxes, 'Amplitude');
    xlim(app.UIAxes, [0 t(end)]);
    ylim(app.UIAxes, [min(app.y_original) max(app.y_original)]);

        end

        % Button pushed function: playcompressedButton
        function playcompressedButtonPushed(app, event)
 if isempty(app.y_original)
        uialert(app.UIFigure, 'No audio loaded.', 'Error');
        return;
    end

    % בדיקת מצב הקומפרסור
    if strcmp(app.compressorSwitch.Value, 'On')
        % יחס דחיסה לפי מצב ה-knob
        switch app.ratioKnob.Value
            case 'Low'
                ratio = 2;
            case 'Medium'
                ratio = 4;
            case 'High'
                ratio = 8;
            otherwise
                ratio = 1;
        end

        % המרת סף ל־dB ללינארי
        threshold = -abs(app.thresholddbSlider.Value);
        x = app.y_original;
        y = compressSignal(app, x, ratio, threshold);

        % בדיקת אם להפעיל מייקאפ גיין
        if strcmp(app.MakeupSwitch.Value, 'On')
            max_in = max(abs(x));
            max_out = max(abs(y));
            if max_out > 0
                y = y * (max_in / max_out);  % הגבר מותאם
            end
            plotColor = 'm';
            legendText = {'Original', 'Compressed + Gain'};
            plotTitle = 'Original vs Compressed (+Make-up Gain)';
        else
            plotColor = 'r';
            legendText = {'Original', 'Compressed'};
            plotTitle = 'Original vs Compressed';
        end

        % עדכון משתנה הפלט והניגון
        app.CompressedSignal = y;
        sound(y, app.fs);

        % ציור הגרפים
        t = (0:length(x)-1)/app.fs;
        cla(app.UIAxes);
        app.UIAxes.XLimMode = 'auto';
        app.UIAxes.YLimMode = 'auto';
        hold(app.UIAxes, 'on');
        plot(app.UIAxes, t, x, 'b');
        plot(app.UIAxes, t, y, plotColor);
        legend(app.UIAxes, legendText);
        title(app.UIAxes, plotTitle);
        xlabel(app.UIAxes, 'Time [s] ');
        ylabel(app.UIAxes, 'Amplitude');
        xlim(app.UIAxes, [0 t(end)]);
        ymax = max([abs(x); abs(y)]);
        ylim(app.UIAxes, [-1.1 1.1] * max(0.01, ymax));
        hold(app.UIAxes, 'off');
    else
        % ניגון רגיל אם הקומפרסור כבוי
        soundsc(app.y_original, app.fs);
        t = (0:length(app.y_original)-1) / app.fs;
        cla(app.UIAxes);
        app.UIAxes.XLimMode = 'auto';
        app.UIAxes.YLimMode = 'auto';
        plot(app.UIAxes, t, app.y_original);
        title(app.UIAxes, 'Original Signal');
        xlabel(app.UIAxes, 'Time [s] ');
        ylabel(app.UIAxes, 'Amplitude');
        xlim(app.UIAxes, [0 t(end)]);
        ylim(app.UIAxes, [min(app.y_original) max(app.y_original)]);

        disp(fs)
        duration = length(y) / fs;
disp(duration)

    end
        end

        % Value changed function: ratioKnob
        function ratioKnobValueChanged(app, event)
            value = app.ratioKnob.Value;
            
        end

        % Value changed function: thresholddbSlider
        function thresholddbSliderValueChanged(app, event)
            value = app.thresholddbSlider.Value;
            
        end

        % Value changed function: sampelingfreqTextArea
        function sampelingfreqTextAreaValueChanged(app, event)
            value = app.sampelingfreqTextArea.Value;
            
        end

        % Value changed function: compressorSwitch
        function compressorSwitchValueChanged(app, event)
            value = app.compressorSwitch.Value;
            updateCompressorLamp(app);
        end

        % Value changed function: MakeupSwitch
        function MakeupSwitchValueChanged(app, event)
            value = app.MakeupSwitch.Value;
            updateMakeupLamp(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {280, 280, 280};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {280, 280};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {214, '1x', 259};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadwavfileButton = uibutton(app.LeftPanel, 'push');
            app.LoadwavfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadwavfileButtonPushed2, true);
            app.LoadwavfileButton.Text = 'Load .wav file';
            app.playoriginalButton = uibutton(app.LeftPanel, 'push');
            app.playoriginalButton.ButtonPushedFcn = createCallbackFcn(app, @playoriginalButtonPushed, true);
            app.playoriginalButton.Text = 'play original';
            app.playcompressedButton = uibutton(app.LeftPanel, 'push');
            app.playcompressedButton.ButtonPushedFcn = createCallbackFcn(app, @playcompressedButtonPushed, true);
            app.playcompressedButton.Text = 'play compressed';
            app.sampelingfreqTextAreaLabel = uilabel(app.LeftPanel);
            app.sampelingfreqTextAreaLabel.Text = 'sampeling freq';
            app.sampelingfreqTextArea = uitextarea(app.LeftPanel);
            app.sampelingfreqTextArea.ValueChangedFcn = createCallbackFcn(app, @sampelingfreqTextAreaValueChanged, true);
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Wave form')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ZTick = [];
            app.RightPanel = uipanel(app.GridLayout);
            app.ratioKnobLabel = uilabel(app.RightPanel);
            app.ratioKnobLabel.Text = 'ratio';
            app.ratioKnob = uiknob(app.RightPanel, 'discrete');
            app.ratioKnob.ValueChangedFcn = createCallbackFcn(app, @ratioKnobValueChanged, true);
            app.thresholddbLabel = uilabel(app.RightPanel);
            app.thresholddbLabel.Text = 'threshold [db]';
            app.thresholddbSlider = uislider(app.RightPanel);
            app.thresholddbSlider.Limits = [-60 0];
            app.thresholddbSlider.ValueChangedFcn = createCallbackFcn(app, @thresholddbSliderValueChanged, true);
            app.compressorSwitchLabel = uilabel(app.RightPanel);
            app.compressorSwitchLabel.Text = 'compressor';
            app.compressorSwitch = uiswitch(app.RightPanel, 'toggle');
            app.compressorSwitch.ValueChangedFcn = createCallbackFcn(app, @compressorSwitchValueChanged, true);
            app.Lamp = uilamp(app.RightPanel);
            app.CompressorLabel = uilabel(app.RightPanel);
            app.CompressorLabel.Text = 'Compressor';
            app.MakeupSwitchLabel = uilabel(app.RightPanel);
            app.MakeupSwitchLabel.Text = 'Makeup';
            app.MakeupSwitch = uiswitch(app.RightPanel, 'slider');
            app.MakeupSwitch.ValueChangedFcn = createCallbackFcn(app, @MakeupSwitchValueChanged, true);
            app.Lamp_2 = uilamp(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wavPlayer_compressor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end